classdef microgliaTimelapseClusterCellPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CCGroupNumDropDown        matlab.ui.control.DropDown
        CCGroupNumDropDownLabel   matlab.ui.control.Label
        SelectionTypeButtonGroup  matlab.ui.container.ButtonGroup
        ClusterROIGroupingButton  matlab.ui.control.RadioButton
        VascularPlexusButton      matlab.ui.control.RadioButton
        ClusterCellButton         matlab.ui.control.RadioButton
        saveButton                matlab.ui.control.Button
        LoadFileButton            matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        imageWaveColAx            matlab.ui.control.UIAxes
        imageDFAx                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        disStack % Description
        exStruct
        col
        cellNum
        cellClusterFlag 
        scatterH
        scatterH2
        BV_poly
        BV_Position
        CC_colours
        CC_Group_poly = cell(1,10);
        CC_GroupPositions = cell(1,10);
    end
    
    methods (Access = private)
        
        function app = loadImages(app)

            exStruct = app.exStruct;

            % get filepath strings
            timeLapse_filepath = [exStruct.filePath(1:end-4) '.tif'];
            labelFilepath = [exStruct.filePath(1:end-4) '-labelMasks.tif'];

            % intial color mask set up
            app.cellNum = max(app.exStruct.csvTab.Object_Label);
            app.col = distinguishable_colors(app.cellNum,'k');
            app.col = [0 0 0; app.col];

            % load in dF images and waveCol Images
            timeLapseTifStack = read_Tiffs(timeLapse_filepath);

            disStack.dFTifStack = imadjustn(timeLapseTifStack);
            labelStack = tiffreadVolume(labelFilepath);

            % load in blood vessel images if exist
            if isfield(exStruct, 'filePathBloodVessel')
                disStack.bloodVessels = read_Tiffs(exStruct.filePathBloodVessel);
                disStack.bloodVessels  = imadjustn(disStack.bloodVessels);
            end


            for i = 1:size(labelStack,3)
                disStack.labelStack(:,:,i,:) = ind2rgb(labelStack(:,:,i),app.col);
            end

            app.disStack = disStack;

            % set up cell delete flags
            app.cellClusterFlag = false(app.cellNum,1);

            % resize image axis to image size
            app.imageDFAx.DataAspectRatio = [size(timeLapseTifStack,1), size(timeLapseTifStack,2),1];
            app.imageDFAx.XLim = [ 0 size(timeLapseTifStack,1)];
            app.imageDFAx.YLim = [ 0 size(timeLapseTifStack,1)];

            app.imageWaveColAx.DataAspectRatio = [size(timeLapseTifStack,1), size(timeLapseTifStack,2),1];
            app.imageWaveColAx.XLim = [ 0 size(timeLapseTifStack,1)];
            app.imageWaveColAx.YLim = [ 0 size(timeLapseTifStack,1)];

            linkaxes([app.imageDFAx,app.imageWaveColAx]);
            

            % set images and slider
            frame = 1;
            setImagesSlider(app, frame);

             % bring to front
                figure(app.UIFigure);
        end
        
        function results = setImagesSlider(app, frame)

            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text,'Cluster Cell')
                imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame));

            elseif strcmp(selectedButton.Text,'Cluster ROI Grouping')
                imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame)); 
                
            else
                try
                    imagesc(app.imageDFAx,app.disStack.bloodVessels(:,:,frame));
                catch
                    imagesc(app.imageDFAx,app.disStack.bloodVessels(:,:));
                end
            end

            app.imageDFAx.Children.ButtonDownFcn = app.imageDFAx.ButtonDownFcn; % copy button down function to image

            imagesc(app.imageWaveColAx,squeeze(app.disStack.labelStack(:,:,frame,:)));
            app.imageWaveColAx.Children.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn; % copy button down function to image

            app.Slider.Value = frame;

            plotClusterCells(app);
            plotBloodVesselBoundary(app);
            plotCC_GroupBoundary(app);

        end
        
        function cellIndx = findClosestCell(app, clickPt)

            currentFrameObj = app.exStruct.csvTab(app.exStruct.csvTab.Centroid_Time_Frames == app.Slider.Value,:);

            currentXY = [currentFrameObj.Centroid_X_Pixel, currentFrameObj.Centroid_Y_Pixel];
            [d,indx] = pdist2(currentXY,clickPt,"euclidean","Smallest",1);

            cellIndx =currentFrameObj.Object_Label(indx);
            
        end
        
        function results = plotClusterCells(app)
            delete(app.scatterH);
            delete(app.scatterH2);

            pause(0.1);

            currentFrameObj = app.exStruct.csvTab(app.exStruct.csvTab.Centroid_Time_Frames == app.Slider.Value,:);

            cell2Plot = find(app.cellClusterFlag);

            if ~isempty(cell2Plot)

                count = 1;
                for tt = 1:length(cell2Plot)

                    if ismember(cell2Plot(tt),currentFrameObj.Object_Label)
                        plotX(count) = currentFrameObj.Centroid_X_Pixel(currentFrameObj.Object_Label == cell2Plot(tt));
                        plotY(count) = currentFrameObj.Centroid_Y_Pixel(currentFrameObj.Object_Label == cell2Plot(tt));
                        count = count+1;
                    end
                end

                hold(app.imageWaveColAx,'on');
                app.scatterH = scatter(app.imageWaveColAx,plotX, plotY,160,'r*');
                app.scatterH.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn;
                hold(app.imageWaveColAx,'off');


                hold(app.imageDFAx,'on');
                app.scatterH2 = scatter(app.imageDFAx,plotX, plotY,160,'r*');
                app.scatterH2.ButtonDownFcn = app.imageDFAx.ButtonDownFcn;
                hold(app.imageDFAx,'off');
            end

        end
        
        function results = plotBloodVesselBoundary(app)

            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                % draw polygon shape
                case 'Cluster Cell' 
                    if ~isempty(app.BV_poly)
                        if ~isvalid(app.BV_poly)
                            images.roi.Polygon(app.imageDFAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Label', 'Plexus');
                        end
                    end

                    % place editable polygon shape
                case 'Vascular Plexus'
                    if ~isempty(app.BV_poly)
                        if ~isvalid(app.BV_poly)
                            app.BV_poly = drawpolygon(app.imageDFAx,'Position', app.BV_Position, 'Label', 'Plexus');
                            addlistener(app.BV_poly,'ROIMoved',@app.roiUpdater);
                            app.BV_Position = app.BV_poly.Position;
                        end
                    end

                case 'Cluster ROI Grouping'
                    if ~isempty(app.BV_poly)
                        if ~isvalid(app.BV_poly)
                            images.roi.Polygon(app.imageDFAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Label', 'Plexus');
                        end
                    end
            end
        end
        
        function app = roiUpdater(app,src,evt)

            if strcmp(src.Label,'Plexus')
                app.BV_poly = src;
                app.BV_poly.Position = evt.CurrentPosition;
                app.BV_Position = evt.CurrentPosition;
            end

            if contains(src.Label, 'Group')
                CC_num = str2double(extract(src.Label, digitsPattern));

                app.CC_Group_poly{CC_num} = src;
                app.CC_Group_poly{CC_num}.Position = evt.CurrentPosition;
                app.CC_GroupPositions{CC_num} = evt.CurrentPosition;

            end
        end
        
        function results = plotCC_GroupBoundary(app)
            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                % draw polygon shape
                case 'Cluster Cell'
                    for i = 1:length(app.CC_Group_poly)
                        if ~isempty(app.CC_Group_poly{i})
                            if ~isvalid(app.CC_Group_poly{i})
                                images.roi.Polygon(app.imageDFAx,'Position', app.CC_GroupPositions{i}, 'Label', ['Group' num2str(i)], 'Color', app.CC_colours(i,:), 'FaceSelectable', 0);
                            end
                        end
                    end

                    % place editable polygon shape
                case 'Vascular Plexus'
                    for i = 1:length(app.CC_Group_poly)
                        if ~isempty(app.CC_Group_poly{i})
                            if ~isvalid(app.CC_Group_poly{i})
                                images.roi.Polygon(app.imageDFAx,'Position', app.CC_GroupPositions{i}, 'Label', ['Group' num2str(i)], 'Color', app.CC_colours(i,:), 'FaceSelectable', 0);
                            end
                        end
                    end

                case 'Cluster ROI Grouping'
                    for i = 1:length(app.CC_Group_poly)
                        if ~isempty(app.CC_Group_poly{i})
                            if ~isvalid(app.CC_Group_poly{i})
                                app.CC_Group_poly{i} = drawpolygon(app.imageDFAx,'Position', app.CC_GroupPositions{i}, 'Label', ['Group' num2str(i)], 'Color', app.CC_colours(i,:));
                                addlistener(app.CC_Group_poly{i},'ROIMoved',@app.roiUpdater);
                            end
                        end
                    end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % center figure on startup
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.csv'};
            [f, p] = uigetfile(filterspec, 'Select .csv file to load');

            filterspec = {[p '*.tif']};
            [f2, p2] = uigetfile(filterspec, 'Select .tif file for blood vessels');

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                opts = detectImportOptions([p f],'NumHeaderLines',0);
                csvTab = readtable([p f], opts);
                app.exStruct.csvTab = csvTab;
                app.exStruct.filePath = [p f];

                % if you select a blood vessel image
                if (ischar(p2))
                    app.exStruct.filePathBloodVessel = [p2 f2];
                end
                loadImages(app);

                % intial slider setup

                if size(app.disStack.labelStack,3) >1
                    app.Slider.Limits = [1 size(app.disStack.labelStack,3)];
                    app.Slider.MajorTicks = [1 : size(app.disStack.labelStack,3)];
                    app.Slider.Value = 1;
                end

                % inital CC group colour set up
                app.CC_colours = distinguishable_colors(10, {'k','w', 'b'});

             end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            frame = round(app.Slider.Value); % integer round

            app.Slider.Value = frame; % set
            
            setImagesSlider(app, frame);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key

                case 'rightarrow' % increase frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame +1; % set

                    if newVal >app.Slider.Limits(2)
                        newVal = app.Slider.Limits(2);
                    end

                    setImagesSlider(app, newVal);

                case 'leftarrow' % decrease frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame -1; % set

                    if newVal < app.Slider.Limits(1)
                        newVal = app.Slider.Limits(1);
                    end

                    setImagesSlider(app, newVal);

            end
            
        end

        % Window scroll wheel function: UIFigure
        function ScrollWheelCallbck(app, event)
%             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            frame = round(app.Slider.Value); % integer round
            newVal = frame  + verticalScrollCount; % set

            if newVal < app.Slider.Limits(1)
                newVal = app.Slider.Limits(1);
            elseif newVal >app.Slider.Limits(2)
                newVal = app.Slider.Limits(2);
            end

            setImagesSlider(app, newVal);
            
        end

        % Button down function: imageWaveColAx
        function imageWaveColAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % find closest cell center
            cellIndx = findClosestCell(app, clickPt);

            % flip the logical
            app.cellClusterFlag(cellIndx) = ~app.cellClusterFlag(cellIndx);

            plotClusterCells(app);

        end

        % Button down function: imageDFAx
        function imageDFAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % find closest cell center
            cellIndx = findClosestCell(app, clickPt);

            % flip the logical
            app.cellClusterFlag(cellIndx) = ~app.cellClusterFlag(cellIndx);

            plotClusterCells(app)

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            
            % cluster cell flag
            clusterCellFlagFile = find(app.cellClusterFlag);

            cellFlags = zeros(height(app.exStruct.csvTab),1);
            for c = 1: length(clusterCellFlagFile)
  
                curCell = (clusterCellFlagFile(c));
                cellFlags(app.exStruct.csvTab.Object_Label==curCell) = 1;
            end

            app.exStruct.csvTab.cellClusterFlag = cellFlags;

            % blood vessel area flag
            if ~isempty(app.BV_Position)
            inPlexusFlag = inpolygon(app.exStruct.csvTab.Centroid_X_Pixel,app.exStruct.csvTab.Centroid_Y_Pixel,app.BV_Position(:,1),app.BV_Position(:,2));
            else
                inPlexusFlag = zeros(height(app.exStruct.csvTab),1);
            end

            app.exStruct.csvTab.inPlexusFlag = inPlexusFlag;

            % cluster cell polygon area flags

            inCC_Groups = zeros(height(app.exStruct.csvTab),1);

            for q = 1:length(app.CC_GroupPositions)

                if ~isempty(app.CC_GroupPositions{q})

                    inCC_Boundary = inpolygon(app.exStruct.csvTab.Centroid_X_Pixel,app.exStruct.csvTab.Centroid_Y_Pixel,app.CC_GroupPositions{q}(:,1),app.CC_GroupPositions{q}(:,2));
                    inCC_Boundary = inCC_Boundary * q;
                    inCC_Groups = inCC_Groups + inCC_Boundary;
                end
            end

            app.exStruct.csvTab.inCC_Groups = inCC_Groups;

            % save table
            writetable(app.exStruct.csvTab,[app.exStruct.filePath(1:end-4) '_corrected.csv']);

            % run cleaning script
            cleanMicrogliaCSV_v2([app.exStruct.filePath(1:end-4) '_corrected.csv']);

            % save the cluster cell ROIs
            CC_Group_poly = app.CC_Group_poly;
            save([app.exStruct.filePath(1:end-4) '_ClusterCellPolys.mat'], 'CC_Group_poly');

        end

        % Selection changed function: SelectionTypeButtonGroup
        function SelectionTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                case 'Cluster Cell'
                    setImagesSlider(app, app.Slider.Value);

                case 'Vascular Plexus'
                    setImagesSlider(app, app.Slider.Value);

                    if isempty(app.BV_poly)
                        app.BV_poly = drawpolygon(app.imageDFAx, 'Label', 'Plexus');
                        addlistener(app.BV_poly,'ROIMoved',@app.roiUpdater);
                        app.BV_Position = app.BV_poly.Position;
                    end

                case 'Cluster ROI Grouping'
                    setImagesSlider(app, app.Slider.Value);
                    curCC_GroupNum = app.CCGroupNumDropDown.Value;
                    CC_num = str2double(extract(curCC_GroupNum, digitsPattern));

                    if isempty(app.CC_Group_poly{CC_num})
                        app.CC_Group_poly{CC_num} = drawpolygon(app.imageDFAx, 'Label', curCC_GroupNum, 'Color', app.CC_colours(CC_num,:));
                        addlistener(app.CC_Group_poly{CC_num},'ROIMoved',@app.roiUpdater);
                        app.CC_GroupPositions{CC_num} = app.CC_Group_poly{CC_num}.Position;
                    end
            end

        end

        % Value changed function: CCGroupNumDropDown
        function CCGroupNumDropDownValueChanged(app, event)
            value = app.CCGroupNumDropDown.Value;
            CC_num = str2double(extract(value, digitsPattern));

            if ~isempty(app.CC_Group_poly{CC_num})
                if isvalid(app.CC_Group_poly{CC_num})
                    delete(app.CC_Group_poly{CC_num});
                end
            end

            app.CC_Group_poly{CC_num} = drawpolygon(app.imageDFAx, 'Label', value, 'Color', app.CC_colours(CC_num,:));
            addlistener(app.CC_Group_poly{CC_num},'ROIMoved',@app.roiUpdater);
            app.CC_GroupPositions{CC_num} = app.CC_Group_poly{CC_num}.Position;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollWheelCallbck, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imageDFAx = uiaxes(app.UIFigure);
            app.imageDFAx.XLim = [0 512];
            app.imageDFAx.YLim = [0 512];
            app.imageDFAx.XTick = [];
            app.imageDFAx.YTick = [];
            app.imageDFAx.ZTick = [];
            app.imageDFAx.ButtonDownFcn = createCallbackFcn(app, @imageDFAxButtonDown, true);
            colormap(app.imageDFAx, 'gray')
            app.imageWaveColAx = uiaxes(app.UIFigure);
            app.imageWaveColAx.XLim = [0 512];
            app.imageWaveColAx.YLim = [0 512];
            app.imageWaveColAx.XTick = [];
            app.imageWaveColAx.YTick = [];
            app.imageWaveColAx.ZTick = [];
            app.imageWaveColAx.ButtonDownFcn = createCallbackFcn(app, @imageWaveColAxButtonDown, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load in Experiment CSV';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save CSV';
            app.SelectionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionTypeButtonGroupSelectionChanged, true);
            app.SelectionTypeButtonGroup.Title = 'Selection Type';
            app.ClusterCellButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.ClusterCellButton.Text = 'Cluster Cell';
            app.ClusterCellButton.Value = true;
            app.VascularPlexusButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.VascularPlexusButton.Text = 'Vascular Plexus';
            app.ClusterROIGroupingButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.ClusterROIGroupingButton.Text = 'Cluster ROI Grouping';
            app.CCGroupNumDropDownLabel = uilabel(app.UIFigure);
            app.CCGroupNumDropDownLabel.Text = 'CC Group Num';
            app.CCGroupNumDropDown = uidropdown(app.UIFigure);
            app.CCGroupNumDropDown.Items = {'Group 1', 'Group 2', 'Group 3', 'Group 4', 'Group 5', 'Group 6', 'Group 7', 'Group 8', 'Group 9', 'Group 10'};
            app.CCGroupNumDropDown.ValueChangedFcn = createCallbackFcn(app, @CCGroupNumDropDownValueChanged, true);
            app.CCGroupNumDropDown.Value = 'Group 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microgliaTimelapseClusterCellPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end